classdef coolingSimulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        AmbientTemperaturedegCEditFieldLabel  matlab.ui.control.Label
        AmbientTemperaturedegCEditField  matlab.ui.control.NumericEditField
        UITable                      matlab.ui.control.Table
        CoolingSimulationLabel       matlab.ui.control.Label
        TargetdegCminEditFieldLabel  matlab.ui.control.Label
        TargetdegCminEditField       matlab.ui.control.NumericEditField
        Switch                       matlab.ui.control.Switch
        TextArea                     matlab.ui.control.TextArea
        AddButton                    matlab.ui.control.Button
        RemoveButton                 matlab.ui.control.Button
        CalculateButton              matlab.ui.control.Button
        HelpButton                   matlab.ui.control.Button
        HoldonCheckBox               matlab.ui.control.CheckBox
        InfoLabel                    matlab.ui.control.Label
        ShowtargetCheckBox           matlab.ui.control.CheckBox
    end

    properties (Access = private)
        AmbientTemperature = 0; % Ambient temperature of the system (degC)
        legendArray = {}; % For identifying the different plots
        legendCounter = -1; % Keeps count of how many legends need to be printed
        plotStyles = ["k--", "k:", "k-.", "k-"]; % Varies the plot for ambient temperature
        plotCounter = -1; % Decides which plot type will be used
        percentage = 0.01; % Percentage for tolerance
        extrapolate = 5; % Amount of extrapolating from data points
        N = 1000; % Amount of data points for linspace
        target = [0,0]; % Target temperature or time
        maxTime = 240; % (min) Corresponds to 4h
        targetPlotList = []; % Helps replotting the target lines
    end
    
    methods (Access = private)
        
        function [] = resetTextArea(app)
            app.TextArea.Value = "";
        end
        
        function [] = drawSolution(app, t, duration)
            app.legendCounter = app.legendCounter + 3;
            app.plotCounter = app.plotCounter + 1;
            
            if app.HoldonCheckBox.Value == 0
                app.legendArray = {};
                app.legendCounter = 1;
                app.plotCounter = 0;
                hold(app.UIAxes, 'off')
                app.UIAxes.cla
            end
            
            tdata = linspace(-app.extrapolate, duration+app.extrapolate, app.N);
            ydata = NCL(app,t,tdata);
            
            [~,I] = max(abs(app.AmbientTemperature*ones(length(app.UITable.Data(:,2)),1) - app.UITable.Data(:,2)));
            diff = app.AmbientTemperature - app.UITable.Data(I,2);
            coefficient = 0.25; % A scaling coefficient for axis
            epsilon = 0.000001; % For axis limits
            
            if diff <= 0
                axisLimit = [0 duration+epsilon app.AmbientTemperature-coefficient*abs(diff) app.AmbientTemperature+abs(diff)+1];
                axis(app.UIAxes, axisLimit)
            else
                axisLimit = [0 duration+epsilon app.AmbientTemperature-abs(diff)-1 app.AmbientTemperature+coefficient*abs(diff)];
                axis(app.UIAxes, axisLimit)
            end
            
            plot(app.UIAxes, app.UITable.Data(:,1), app.UITable.Data(:,2), '.', 'MarkerSize', 20)
            hold(app.UIAxes, 'on')
            plot(app.UIAxes, tdata, ydata)
            
            if app.Switch.Value == "Temperature"
                if checkTarget(app,t)
                app.TextArea.Value = "The target temperature of " + num2str(app.target(2),3) + ...
                        " degC is reached after around " + num2str(app.target(1),3) + " minutes.";
                    targetPlot(app,t, axisLimit)
                else
                    app.TextArea.Value = "Cannot reach target temperature.";
                    if length(app.targetPlotList) == 3
                        delete(app.targetPlotList(1))
                        delete(app.targetPlotList(2))
                        delete(app.targetPlotList(3))
                        app.targetPlotList = [];
                    end
                end
            else
                if app.target(1) >= app.maxTime
                    app.TextArea.Value = "You have exceeded the maximum waiting time.";
                    if length(app.targetPlotList) == 3
                        delete(app.targetPlotList(1))
                        delete(app.targetPlotList(2))
                        delete(app.targetPlotList(3))
                        app.targetPlotList = [];
                    end
                else
                    app.TextArea.Value = "After " + num2str(app.target(1),3) + " minutes, the temperature is " + num2str(NCL(app,t,app.target(1)),3) + " degC.";
                    targetPlot(app,t, axisLimit)
                end
            end
            
            yline(app.UIAxes, app.AmbientTemperature, app.plotStyles(1,mod(app.plotCounter, length(app.plotStyles))+1))
             
            app.legendArray{1,app.legendCounter} = 'Data points';
            app.legendArray{1,app.legendCounter+1} = [num2str(app.AmbientTemperature,3), ' + ', num2str(t(1),3), 'exp(-', num2str(t(2),3), 't) (degC)']; 
            app.legendArray{1,app.legendCounter+2} = ['Ambient temperature of ', num2str(app.AmbientTemperature,3), ' degC'];
            
            legend(app.UIAxes, app.legendArray)
          
        end
        
        function fun = NCL(app,t,tdata)
            fun = app.AmbientTemperature + t(1)*exp(-t(2)*tdata); % Newton's Cooling Law 
        end
        
        function [] = checkInput(app)
            % Checking for erroneous inputs
            for i=1:size(app.UITable.Data,2)
                for j=1:size(app.UITable.Data,1)
                    if isnan(app.UITable.Data(j,i))
                        app.TextArea.Value = "Please check data.";
                        app.AddButton.Enable = false;
                        app.CalculateButton.Enable = false;
                        return
                    end
                end
            end
            
            app.AddButton.Enable = true;
            app.CalculateButton.Enable = true;
            
        end
        
        function duration = getDuration(app,t,target)
            duration = 0;
            tolerance = app.percentage * target; % Cool down up to 99% of target temperature
            maxDuration = app.maxTime; % corresponds to 4h
            while abs(NCL(app,t,duration) - target) > tolerance
                if duration == maxDuration
                    break
                end
                
                duration = duration + 1;
                
            end
        end
        
        function [] = calculateSolution(app)
            x0 = [20; 0.05]; % Initial guess
            t = lsqcurvefit(@app.NCL, x0, app.UITable.Data(:,1), app.UITable.Data(:,2)); % Solution with LSQ method
            
            duration = getDuration(app,t,app.AmbientTemperature);
            
            drawSolution(app, t, duration)
        end
        
        function isValid = checkTarget(app, t)
            if app.target(2) < app.AmbientTemperature && t(1) > 0
                isValid = false;
                return
                
            elseif app.target(2) > app.AmbientTemperature && t(1) < 0 
                isValid = false;
                return
                
            elseif app.target(2) <= app.AmbientTemperature && t(1) < 0
                app.target(1) = getDuration(app,t,app.target(2));
                if app.target(1) < app.maxTime
                    isValid = true;
                    return
                else
                    isValid = false;
                    return
                end
                
            elseif app.target(2) >= app.AmbientTemperature && t(1) > 0
                app.target(1) = getDuration(app,t,app.target(2));
                if app.target(1) < app.maxTime
                    isValid = true;
                    return
                else
                    isValid = false;
                    return
                end
            end
        end
        
        function [] = targetPlot(app,t, axisLimit)
            if app.ShowtargetCheckBox.Value == true
                yValue = NCL(app,t,app.target(1));
                if length(app.targetPlotList) == 3
                    delete(app.targetPlotList(1))
                    delete(app.targetPlotList(2))
                    delete(app.targetPlotList(3))
                end
                p1 = plot(app.UIAxes, app.target(1), yValue, 'kx', 'MarkerSize', 20, 'HandleVisibility', "off");
                p2 = plot(app.UIAxes, [app.target(1), app.target(1)], [axisLimit(3), yValue], 'k-', 'HandleVisibility', "off"); % vertical line
                p3 = plot(app.UIAxes, [axisLimit(1), app.target(1)], [yValue, yValue], 'k-', 'HandleVisibility', "off"); % horizontal line
                app.targetPlotList(1) = p1;
                app.targetPlotList(2) = p2;
                app.targetPlotList(3) = p3;
            else
                if length(app.targetPlotList) == 3
                    delete(app.targetPlotList(1))
                    delete(app.targetPlotList(2))
                    delete(app.targetPlotList(3))
                    app.targetPlotList = [];
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.AmbientTemperaturedegCEditField.Value = 20;
            app.AmbientTemperaturedegCEditField.Limits = [0,Inf];
            app.AmbientTemperature = app.AmbientTemperaturedegCEditField.Value;
            
            app.UIAxes.Title.String = "Newton's Cooling Law";
            
            app.TargetdegCminEditField.Value = 25;
            app.target(2) = app.TargetdegCminEditField.Value;
            
            app.TextArea.Editable = false;
            
            app.UITable.Data = [0 34; 16 27]; % Example data
            app.UITable.ColumnEditable = true;
            
            app.ShowtargetCheckBox.Value = true;
            
            calculateSolution(app)
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            resetTextArea(app)
            app.RemoveButton.Enable = true;
            app.UITable.Data(end+1,:) = 0; % Add row with zeros
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            resetTextArea(app)
            
            if size(app.UITable.Data,1) > 2
                app.UITable.Data(end,:) = []; % Remove last row
            else
                app.RemoveButton.Enable = false;
                app.TextArea.Value = "Must have at least two data points.";
            end
            
            checkInput(app)
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            resetTextArea(app)
            checkInput(app)
        end

        % Value changed function: AmbientTemperaturedegCEditField
        function AmbientTemperaturedegCEditFieldValueChanged(app, event)
            value = app.AmbientTemperaturedegCEditField.Value;
            app.AmbientTemperature = value;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            calculateSolution(app)
        end

        % Value changed function: TargetdegCminEditField
        function TargetdegCminEditFieldValueChanged(app, event)
            value = app.TargetdegCminEditField.Value;
            if app.Switch.Value == "Temperature"
                app.target(2) = value;
            else
                app.target(1) = value;
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if value == "Temperature"
                app.TargetdegCminEditField.Limits = [-Inf,Inf];
                app.target(2) = app.TargetdegCminEditField.Value;
            else
                app.TargetdegCminEditField.Limits = [0,Inf];
                app.target(1) = app.TargetdegCminEditField.Value;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Time (min)')
            ylabel(app.UIAxes, 'Temperature (degC)')
            app.AmbientTemperaturedegCEditFieldLabel = uilabel(app.UIFigure);
            app.AmbientTemperaturedegCEditFieldLabel.Text = 'Ambient Temperature (degC)';
            app.AmbientTemperaturedegCEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmbientTemperaturedegCEditField.ValueChangedFcn = createCallbackFcn(app, @AmbientTemperaturedegCEditFieldValueChanged, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Time (min)'; 'Temperature (degC)'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.CoolingSimulationLabel = uilabel(app.UIFigure);
            app.CoolingSimulationLabel.Text = 'Cooling Simulation';
            app.TargetdegCminEditFieldLabel = uilabel(app.UIFigure);
            app.TargetdegCminEditFieldLabel.Text = 'Target (degC / min)';
            app.TargetdegCminEditField = uieditfield(app.UIFigure, 'numeric');
            app.TargetdegCminEditField.ValueChangedFcn = createCallbackFcn(app, @TargetdegCminEditFieldValueChanged, true);
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Temperature', 'Time'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Temperature';
            app.TextArea = uitextarea(app.UIFigure);
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.RemoveButton = uibutton(app.UIFigure, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.Text = 'Help';
            app.HoldonCheckBox = uicheckbox(app.UIFigure);
            app.HoldonCheckBox.Text = 'Hold on';
            app.InfoLabel = uilabel(app.UIFigure);
            app.InfoLabel.Text = 'Info:';
            app.ShowtargetCheckBox = uicheckbox(app.UIFigure);
            app.ShowtargetCheckBox.Text = 'Show target';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = coolingSimulation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end