classdef solutionCooling < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        AmbientTemperaturedegCEditFieldLabel  matlab.ui.control.Label
        AmbientTemperaturedegCEditField  matlab.ui.control.NumericEditField
        UITable                         matlab.ui.control.Table
        SolutionCoolingSimulationLabel  matlab.ui.control.Label
        TargetdegCminEditFieldLabel     matlab.ui.control.Label
        TargetdegCminEditField          matlab.ui.control.NumericEditField
        Switch                          matlab.ui.control.Switch
        TextArea                        matlab.ui.control.TextArea
        AddButton                       matlab.ui.control.Button
        RemoveButton                    matlab.ui.control.Button
        CalculateButton                 matlab.ui.control.Button
        HelpButton                      matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function [] = resetTextArea(app)
            app.TextArea.Value = "";
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.AmbientTemperaturedegCEditField.Value = 20;
            app.AmbientTemperaturedegCEditField.Limits = [0,Inf];
            app.UIAxes.Title.String = "Newton's Cooling Law";
            app.TargetdegCminEditField.Value = 25;
            app.TextArea.Editable = false;
            app.UITable.Data = [0 34; 16 27]; % Example data
            app.UITable.ColumnEditable = true;
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            resetTextArea(app)
            app.UITable.Data(end+1,:) = 0; % Add row with zeros
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            resetTextArea(app)
            
            if size(app.UITable.Data,1) > 2
                app.UITable.Data(end,:) = []; % Remove last row
            else
                app.TextArea.Value = "Must have at least two data points.";
            end
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            newData = event.NewData;
            if isnan(newData)
                app.TextArea.Value = "Please check data.";
            else
                resetTextArea(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Time (min)')
            ylabel(app.UIAxes, 'Temperature (degC)')
            app.AmbientTemperaturedegCEditFieldLabel = uilabel(app.UIFigure);
            app.AmbientTemperaturedegCEditFieldLabel.Text = 'Ambient Temperature (degC)';
            app.AmbientTemperaturedegCEditField = uieditfield(app.UIFigure, 'numeric');
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Time (min)'; 'Temperature (degC)'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.SolutionCoolingSimulationLabel = uilabel(app.UIFigure);
            app.SolutionCoolingSimulationLabel.Text = 'Solution Cooling Simulation';
            app.TargetdegCminEditFieldLabel = uilabel(app.UIFigure);
            app.TargetdegCminEditFieldLabel.Text = 'Target (degC / min)';
            app.TargetdegCminEditField = uieditfield(app.UIFigure, 'numeric');
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Temperature', 'Time'};
            app.Switch.Value = 'Temperature';
            app.TextArea = uitextarea(app.UIFigure);
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.RemoveButton = uibutton(app.UIFigure, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.Text = 'Calculate';
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.Text = 'Help';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = solutionCooling

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end